classdef testvisage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
 
        [fileName, pathName] = uigetfile('*.jpg;*.png', 'Select Image');
    if fileName ~= 0
        % Chemin complet de l'image sélectionnée
        fullPath = fullfile(pathName, fileName);
        
        % Charger l'image
        I = imread(fullPath);
        
        % Charger le réseau ici (vous devez avoir net déjà défini)
        % net = ... (votre chargement de réseau)
        
        % Appeler la fonction test_network
        test_network(net, I);
    end
end

% Fonction pour tester le réseau
function test_network(net, I)
    G = imresize(I, [224, 224]);
    
    % Classification de l'image
    [label, prob] = classify(net, G);
    
    % Affichage des résultats
    if max(prob) < 0.8
        % Afficher l'image avec le texte comme saut de ligne dans le titre
        figure;
        imshow(G);
        title(sprintf('Personne non détectée\nConfiance : %.2f', max(prob)));
    else
        % Affichage de l'image et du label avec le score de probabilité
        figure;
        imshow(G);
        title({char(label), num2str(max(prob), 2)});
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testvisage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end